classdef Model_TCO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_Sturcture              matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        RotXEditFieldLabel           matlab.ui.control.Label
        EditField_RotX               matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        EditField_RotY               matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        EditField_RotZ               matlab.ui.control.NumericEditField
        Panel_Freq                   matlab.ui.container.Panel
        LocalmodeFreqEditFieldLabel  matlab.ui.control.Label
        EditField_LocFreq            matlab.ui.control.NumericEditField
        LabeledFreq                  matlab.ui.control.Label
        EditField_LabelFreq          matlab.ui.control.NumericEditField
        AnharmonicityLabel           matlab.ui.control.Label
        EditField_Anharm             matlab.ui.control.NumericEditField
        LabelIndex                   matlab.ui.control.Label
        EditField_LabelInd           matlab.ui.control.EditField
        Panel_FigOpt                 matlab.ui.container.Panel
        CheckBox_Atom                matlab.ui.control.CheckBox
        CheckBox_Bond                matlab.ui.control.CheckBox
        CheckBox_Axis                matlab.ui.control.CheckBox
        Button_Generate              matlab.ui.control.Button
        Button_Draw                  matlab.ui.control.Button
        Button_ExportData            matlab.ui.control.Button
        Version                      matlab.ui.control.Label
    end


    properties (Access = public)
        Parent % handles of parent
        Structure % StructureData Class 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
            else
                disp('Running Model_TCO in the stand alone mode...')
            end
            
            % initialize the orientation table
            Init_TCO(app)
            
            % Create the default structure
            Button_GeneratePushed(app,'')
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            Data_TCO.Structure = app.Structure;
            assignin('base','Data_TCO',Data_TCO)
            disp('Updated Data_TCO exported!')
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            %% Read GUI variables
            GUI_Inputs= Parse_TCO(app);
            
            %% Construct molecule
            app.Structure = GetAcid(GUI_Inputs);
            
            % Export into Structure so it can be passsed around different GUIs
            app.Structure.StructModel = 1;   
            
            % export necessary handle and functions
            app.Structure.hPlotFunc = @PlotXYZfiles_Acid;
            app.Structure.hParseGUIFunc = @Parse_TCO;
            app.Structure.hGUIs = app;
            
            % update to COSMOSS
            Export2GUIs(app)
            
            disp('Structure file generated!')
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            hF = app.Structure.Draw;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Model_TCO';
            app.Panel_Sturcture = uipanel(app.UIFigure);
            app.Panel_Sturcture.Title = 'Orientation';
            app.UITable = uitable(app.Panel_Sturcture);
            app.UITable.ColumnName = {''; 'Phi'; 'Psi'; 'Theta'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RotXEditFieldLabel = uilabel(app.Panel_Sturcture);
            app.RotXEditFieldLabel.Text = 'Rot X:';
            app.EditField_RotX = uieditfield(app.Panel_Sturcture, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel_Sturcture);
            app.YEditFieldLabel.Text = 'Y:';
            app.EditField_RotY = uieditfield(app.Panel_Sturcture, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel_Sturcture);
            app.ZEditFieldLabel.Text = 'Z:';
            app.EditField_RotZ = uieditfield(app.Panel_Sturcture, 'numeric');
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Freq. & Anharmonicy (cm-1)';
            app.LocalmodeFreqEditFieldLabel = uilabel(app.Panel_Freq);
            app.LocalmodeFreqEditFieldLabel.Text = 'Local mode Freq.:';
            app.EditField_LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_LocFreq.Value = 1650;
            app.LabeledFreq = uilabel(app.Panel_Freq);
            app.LabeledFreq.Text = 'Labeled Freq.:';
            app.EditField_LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_LabelFreq.Value = 1630;
            app.AnharmonicityLabel = uilabel(app.Panel_Freq);
            app.AnharmonicityLabel.Text = 'Anharmonicity:';
            app.EditField_Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.EditField_Anharm.Value = 10;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.EditField_LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_TCO(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end